classdef UserInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FaceAppGalleryUIFigure  matlab.ui.Figure
        usernamePanel           matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
        loadphotoButton         matlab.ui.control.Button
        SaveButton              matlab.ui.control.Button
        ResetButton             matlab.ui.control.Button
        ButtonGroup             matlab.ui.container.ButtonGroup
        RotateKnobLabel         matlab.ui.control.Label
        RotateKnob              matlab.ui.control.Knob
        FiltersButtonGroup      matlab.ui.container.ButtonGroup
        medianButton            matlab.ui.control.ToggleButton
        GaussianButton          matlab.ui.control.ToggleButton
        zerophaseButton         matlab.ui.control.Button
    end


    properties (Access = private)
        connexion; % FaceApp connexion window
        username; % name of connected user
        chosenImage; % image loaded by the user
        modifiedImage;
        filter = {'*.jpeg';'*.jpg';'*.png';'*.bmp';'*.gif';'*.tiff'};
        logo = imread('FaceAppLogo.jpg');
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ConnexionInterface, user)

             app.connexion = ConnexionInterface;
             app.username = user;
             app.usernamePanel.Title =user;
             app.UIAxes.XLim =[0,640];
             app.UIAxes.YLim =[0,480];
             axis(app.UIAxes,'image');
             imshow(app.logo, 'parent', app.UIAxes);
        end

        % Button pushed function: loadphotoButton
        function loadPhotoPushed(app, event)
             [file,path]=uigetfile(app.filter, 'Select an image.');
              fichier = [path,file];
             app.chosenImage = imread(fichier);
             app.modifiedImage = app.chosenImage;
             imshow(app.chosenImage, 'parent', app.UIAxes);
        end

        % Button pushed function: SaveButton
        function saveButtonChanged(app, event)
             uiputfile(app.filter, 'Select where to save your image');
        end

        % Button pushed function: ResetButton
        function resetButtonPushed(app, event)
           
        end

        % Button pushed function: zerophaseButton
        function ZeroPhasePushed(app, event)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FaceAppGalleryUIFigure = uifigure;
            app.FaceAppGalleryUIFigure.Name = 'FaceApp - Gallery';
            app.usernamePanel = uipanel(app.FaceAppGalleryUIFigure);
            app.usernamePanel.Title = 'username';
            app.UIAxes = uiaxes(app.usernamePanel);
            title(app.UIAxes, 'Enjoy FaceApp!')
            app.loadphotoButton = uibutton(app.usernamePanel, 'push');
            app.loadphotoButton.ButtonPushedFcn = createCallbackFcn(app, @loadPhotoPushed, true);
            app.loadphotoButton.Text = 'load photo';
            app.SaveButton = uibutton(app.usernamePanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonChanged, true);
            app.SaveButton.Text = 'Save';
            app.ResetButton = uibutton(app.usernamePanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ButtonGroup = uibuttongroup(app.FaceAppGalleryUIFigure);
            app.RotateKnobLabel = uilabel(app.ButtonGroup);
            app.RotateKnobLabel.Text = 'Rotate';
            app.RotateKnob = uiknob(app.ButtonGroup, 'continuous');
            app.FiltersButtonGroup = uibuttongroup(app.FaceAppGalleryUIFigure);
            app.FiltersButtonGroup.Title = 'Filters';
            app.medianButton = uitogglebutton(app.FiltersButtonGroup);
            app.medianButton.Text = 'median';
            app.medianButton.Value = true;
            app.GaussianButton = uitogglebutton(app.FiltersButtonGroup);
            app.GaussianButton.Text = 'Gaussian';
            app.zerophaseButton = uibutton(app.FiltersButtonGroup, 'push');
            app.zerophaseButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroPhasePushed, true);
            app.zerophaseButton.Text = 'zero phase';
        end
    end

    methods (Access = public)

        % Construct app
        function app = UserInterface(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FaceAppGalleryUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FaceAppGalleryUIFigure)
        end
    end
end